classdef GUI_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FireSubpaylodPanel              matlab.ui.container.Panel
        FireSubpayloadButton            matlab.ui.control.StateButton
        PayloadRelayStateLamp           matlab.ui.control.Lamp
        PayloadRelayStateLampLabel      matlab.ui.control.Label
        MagnetsPanel                    matlab.ui.container.Panel
        MagnetsSwitch                   matlab.ui.control.Switch
        MagnetsSwitchLabel              matlab.ui.control.Label
        LoopControlsPanel               matlab.ui.container.Panel
        CheckRelayStatusButton          matlab.ui.control.Button
        DataUpdatingLoopSwitch          matlab.ui.control.Switch
        DataUpdatingLoopSwitchLabel     matlab.ui.control.Label
        CoolingSystemPanel              matlab.ui.container.Panel
        StopCoolingClickBeforeClosingCoolingWindowButton  matlab.ui.control.Button
        StartCoolingPushOpenCoolingFirstButton  matlab.ui.control.Button
        ClicktoOpenCoolingInterfaceButton  matlab.ui.control.Button
        RailPumpingSystemPanel          matlab.ui.container.Panel
        Image7                          matlab.ui.control.Image
        ChamberPressureStatusLamp       matlab.ui.control.Lamp
        ChamberPressureStatusLampLabel  matlab.ui.control.Label
        ChamberPressureTorrLabel        matlab.ui.control.Label
        Label11                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RoughingPumpGauge               matlab.ui.control.LinearGauge
        TurboPressureTorrLabel          matlab.ui.control.Label
        ForelinePressureTorrLabel       matlab.ui.control.Label
        RoughingPumpCurrentDrawVLabel   matlab.ui.control.Label
        Label10                         matlab.ui.control.Label
        Label9                          matlab.ui.control.Label
        Label8                          matlab.ui.control.Label
        GateValveCloseChannelLamp       matlab.ui.control.Lamp
        GateValveCloseChannelLampLabel  matlab.ui.control.Label
        GateValveOpenChannelLamp        matlab.ui.control.Lamp
        GateValveOpenChannelLabel       matlab.ui.control.Label
        GateValveSwitch                 matlab.ui.control.Switch
        GateValveSwitchLabel            matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Image6                          matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RoughingPumpSwitch              matlab.ui.control.Switch
        RoughingPumpSwitchLabel         matlab.ui.control.Label
        ForelineSolenoidSwitch          matlab.ui.control.Switch
        ForelineSolenoidSwitchLabel     matlab.ui.control.Label
        Image5                          matlab.ui.control.Image
        TurboSolenoidSwitch             matlab.ui.control.Switch
        TurboSolenoidSwitchLabel        matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        GN2SystemPanel                  matlab.ui.container.Panel
        GN2LinePressurePsiLabel         matlab.ui.control.Label
        Label12                         matlab.ui.control.Label
        GN2SolenoidSwitch               matlab.ui.control.Switch
        GN2SolenoidSwitchLabel          matlab.ui.control.Label
        GN2LinePressurepsiGauge         matlab.ui.control.Gauge
    end

    
    properties (Access = private)
        readdata % converting hex data to decimal data
        tempread % converting hex data for temp to decimal 
        allread % readout from all relays
        allstatus % read status of all relays
        status1 % status of relay 1
        status2 % status of relay 2
        status3 % status of relay 3
        status4 % status of relay 4
        status5 % status of relay 5
        status7 % status of relay 7
        xaxis % x-axis data this will update temperature graph 
        turbo % variable for visa connection to turbo pump
        magnetwarning % variable for magnet popup app
        forelinepress % array to store forline pressures
        turbopress % array to store turbo pressures
        chamberpress % array to store vacuum chamber pressures
        gn2pressure % GN2 Solenoid Pressure
        DialogeApp % Cooling App
        gatepress % array to store gate valve pressure
        roughpress % an array to store roughing pump line pressures
        roughvolt % variable to store roughing voltage
        lampvoltopen % stores the data for the gate valve channels
        lampvoltclosed % stores data for the gate valve channels
        chamupper % upper end of the x axis for the plot
        chamlower % lower end of the plot x axis 
        tick % to pause or resume the data collection
        markercolor % to govern the color of the ticks on the graph
        relay  % relay that will be connected in startup function 
        temponoff % variable that govoerns whether temperature is sensed
        
        readstatus % variable to read status of relays
        measurement % number to keep track number of measurements
        temparray % creating array to store temps for rate
        tempdata % creating array to save temp data
        ratedata % creating array to save rate data
        ratetime % variable to keep track of time for rates
        xll1 % variable to define lower limit of x-axis of plot1
        xul1 % variable to define upper limit of x-axis of plot1
        xll2 % variable to define lower limit of x-axis of plot2
        xul2 % variable to define upper limit of x-axis of plot2
        b % variable to control while loops
        c % controls pressure updating loop 
        currentTemp % variable to store current temperature data
        starttime % variable to calculate cooling rate 
    end

    
    methods (Access = private)

        function updatingfcn(app)
            while app.c < 2
                % ping relay for analog channels
                fwrite(app.relay, app.readdata);
                response = flipud(fread(app.relay));                
                tnow = datetime('now','Format','HH:mm:ss.SSS');
               
                % updating gn2 pressure transducer gauge
                gn2data = response(2);
                app.gn2pressure(end+1) = 35.3269*5*(gn2data/255);
                app.GN2LinePressurepsiGauge.Value = app.gn2pressure(end);      
                app.Label12.Text = string(app.gn2pressure(end));
                % change gauge color
%                 if app.gn2pressure < 60
%                     app.GN2LinePressurepsiGauge.ScaleColors = [1,0,0];
%                     app.GN2LinePressurepsiGauge.ScaleColorLimits = [0,100];                    
%                 elseif app.gn2pressure > 60 & app.gn2pressure < 65
%                     app.GN2LinePressurepsiGauge.ScaleColors = [1,0.41,0.16];
%                     app.GN2LinePressurepsiGauge.ScaleColorLimits = [0,100];  
%                 else 
%                      app.GN2LinePressurepsiGauge.ScaleColors = [0,1,0];
%                     app.GN2LinePressurepsiGauge.ScaleColorLimits = [0,100];                     
%                 end 
                
                % changing magnet pressure
                pressdata = response(6);
                volt = (pressdata/255)*5;
                press = 10^((5.270543073*volt)-9.440425657);
                app.forelinepress(end+1) = press;
                app.Label9.Text = sprintf('%.2e',app.forelinepress(end));
    
                % changing Label Color
                if app.forelinepress(end) <= 7.5e2 & app.forelinepress(end) >= 1.0e2
                    app.Label.BackgroundColor = [1,0,0];
                    app.Label_2.BackgroundColor = [1.00,0.00,0.00];
                elseif app.forelinepress(end) < 1.0e2 & app.forelinepress(end) > 1.0e-1
                    app.Label_2.BackgroundColor = [1.00,0.41,0.16];
                    app.Label.BackgroundColor = [1,.41,.16];
                elseif app.forelinepress(end) <= 1.0e-1 & app.forelinepress(end) >=-1.0e-3
                    app.Label_2.BackgroundColor = [0.00,1.00,0.00];
                    app.Label.BackgroundColor = [0,1,0];
                else 
                    app.Label_2.BackgroundColor = [1,0,0];
                    app.Label.BackgroundColor = [1,0,0];
                end
                %changing roughing pump pressure
                voltdata = response(5);
                roughvoltage = (voltdata/255)*5;
                app.roughvolt = roughvoltage;
                app.Label8.Text = string(app.roughvolt);
    
                app.RoughingPumpGauge.Value = app.roughvolt; 
    
%                 if app.roughvolt <= 3 & app.roughvolt >= 2
%                     app.RoughingPumpGauge.ScaleColors = [0,1,0];
%                     app.RoughingPumpGauge.ScaleColorLimits = [0,5];                   
%                 elseif app.roughvolt > 3 & app.roughvolt < 4
%                     app.RoughingPumpGauge.ScaleColors = [0,1,0];
%                     app.RoughingPumpGauge.ScaleColorLimits = [0,5];       
%                 else 
%                        app.RoughingPumpGauge.ScaleColors = [1,0,0];
%                     app.RoughingPumpGauge.ScaleColorLimits = [0,5];     
%                 end
                
                % Updating Turbo Data
                pressdata = response(8);
                volt = (pressdata/255)*5;
                press = 10^((2.897861824*volt)-5.761436331);
                app.turbopress(end+1) = press;
                app.Label10.Text = sprintf('%.2e',app.turbopress(end));
    
                % changing Label Color
                if app.turbopress(end) > 1.0e2 & app.turbopress(end) > 1.0e-2
                    app.Label_3.BackgroundColor = [1.00,0.00,0.00];
                elseif app.turbopress(end) <= 1.0e2 & app.turbopress(end) >=1.0e-3
                    app.Label_3.BackgroundColor = [1.00,0.41,0.16];
                elseif app.turbopress(end) < 1.0e-3 & app.turbopress(end) >=1.0e-6 
                    app.Label_3.BackgroundColor = [0.00,1.00,0.00];
                else 
                    app.Label_3.BackgroundColor = [1.00,0.00,0.00];                    
                end
    
                % Changing Turbo Lamps
                lampdataopen = response(4);
                lampdataclose = response(3);
                
                app.lampvoltopen = (lampdataopen/255)*5;
                app.lampvoltclosed = (lampdataclose/255)*5;

                if app.lampvoltclosed < 2  
                    app.GateValveCloseChannelLamp.Color = [0,1,0];
                else
                    app.GateValveCloseChannelLamp.Color = [1,0,0];
                end 
    
                if app.lampvoltopen < 2
                    app.GateValveOpenChannelLamp.Color = [1,0,0];
                else 
                    app.GateValveOpenChannelLamp.Color=[0,1,0];
                end 

                % changing chamber pressure
                pressdata = response(7);
                volt = (pressdata/255)*5;
                press = 10^((3.5271*volt)-7.2975);
                app.chamberpress(end+1) = press;
                app.Label11.Text = sprintf('%.2e',app.chamberpress(end));
                
                % Changing Lamp Status and Label Color
                if app.chamberpress(end) >= 1.0e2
                    app.ChamberPressureStatusLamp.Color = [1.00,0.00,0.00];
                    app.Label_4.BackgroundColor = [1.00,0.00,0.00];
                    app.markercolor = [1,0,0];
                elseif app.chamberpress(end) < 1.0e2 & app.chamberpress(end) > 1.0e-4
                    app.ChamberPressureStatusLamp.Color = [1.00,0.41,0.16];
                    app.Label_4.BackgroundColor = [1.00,0.41,0.16];
                    app.markercolor=[1,0.41,0.16];
                else
                    app.ChamberPressureStatusLamp.Color = [0,1,0];
                    app.Label_4.BackgroundColor = [0.00,1.00,0.00];
                    app.markercolor=[0,1,0];
%                 elseif app.chamberpress <= 1.0e-3 & app.chamberpress >= 1.0e-6
%                     app.ChamberPressureStatusLamp.Color = [0,1,0];
%                     app.Label_4.BackgroundColor = [0.00,1.00,0.00];
%                     app.markercolor=[0,1,0];
%                 else 
%                     app.ChamberPressureStatusLamp.Color = [1.00,0.00,0.00];  
%                     app.markercolor = [1,0,0];
                end
                pause(0.5) % dont remove this or the app never starts!!!
                if app.c > 0
                    app.c = 2;
                    return
                end 
            end 
        end
        function timer1fcn(app)
            if app.temponoff == 1
                updateplots(app.DialogeApp,0,0,1,0,app.temponoff,0);
                return
            end 
            app.measurement = 1;
            app.b=0;
            % reading relay and finding temperature and cooling rate
            fwrite(app.relay, app.tempread)
            try
                response = fread(app.relay);
                data = response(end-1);
                volts = ((data/255)*10)/2;
                temp = (volts*100)-273.15;
            catch 
                updateplots(app.DialogeApp, 0,0,0,1,app.temponoff,0);
                temp = app.temparray(end);
            end
            if temp < -105 
                temp = app.temparray(end);
            end
            if temp < -95 
                app.b = 2;
            end
            app.tempdata(end+1) = temp;
            app.temparray(end+1) = temp;

            % creating initial starttime of cooling
            app.starttime = datetime('now');
    
            while app.b < 1
                % turn LN2 on
                fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x71'; '0x01'; '0x1D']))
                % setting status of ln2 lamp
                fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x79'; '0x01'; '0x25']))
                try
                    response = fread(app.relay);
                    updateplots(app.DialogeApp,0,0,0,0,2,app.temponoff,response);
                catch 
                    updateplots(app.DialogeApp,0,0,1,0,app.temponoff,response);
                end
                app.b=0;
                i = 1;
                while i < 11
                    i = i + 1;
                    % reading relay and finding temperature and cooling rate
                    fwrite(app.relay, app.tempread);
                    try
                    response = fread(app.relay);
                    data = response(end-1);
                    volts = ((data/255)*10)/2;
                    temp = (volts*100)-273.15;
                    catch 
                        temp = app.temparray(end);
                        updateplots(app.DialogeApp,0,0,1,0, app.temponoff,0);
    %                                 if strcmp(app.ErrorLogTextArea.Value,'')
    %                                     t = datetime('now');
    %                                     message_disp = append(datestr(t),' Failed to receive analog response from relay board.');
    %                                 else
    %                                     t = datetime('now');
    %                                     message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to receive analog response from relay board.')}];
    %                                 end
    %                                 app.ErrorLogTextArea.Value = message_disp;
                    end
                    if temp < -105
                        temp = app.temparray(end);
                    end
                    if temp < -98
                        app.b = 2;
                    end 
                    app.temparray(end+1) = temp;
                    app.tempdata(end+1) = temp;

                    % opening turbo solenoid to make sure
                    fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x6C'; '0x01'; '0x18']))
    
                    % plotting the temperature on first figure
                    updateplots(app.DialogeApp,temp,0,0,0, app.temponoff,0);
                    if app.c == 1
                        updatingfcn(app);
                        app.c = 1;
                    end 
                end
    
                %close the solenoid
                fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x69'; '0x01'; '0x15']))
    
                % setting status of ln2 lamp
                fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x79'; '0x01'; '0x25']))
                response = fread(app.relay);
                try
                    updateplots(app.DialogeApp,0,0,0,2, app.temponoff,response);
    %                             response = fread(app.relay);
    %                             if response(end-1)==0
    %                                 app.LN2SolenoidLamp.Enable = 'off';
    %                             else
    %                                 app.LN2SolenoidLamp.Enable = 'on';
    %                             end
                catch 
                    updateplots(app.DialogeApp,0,0,1,0, app.temponoff,0);
    %                             if strcmp(app.ErrorLogTextArea.Value,'')
    %                                 t = datetime('now');
    %                                 message_disp = append(datestr(t),' Failed to receive analog response from relay board.');
    %                             else
    %                                 t = datetime('now');
    %                                 message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to receive analog response from relay board.')}];
    %                             end
    %                             app.ErrorLogTextArea.Value = message_disp;
                end
                
                i = 1;
                while i < 16
                    i = i + 1;
                    % reading relay and finding temperature and cooling rate
                    fwrite(app.relay, app.tempread)
                    try
                        response = fread(app.relay);
                        data = response(end-1);
                        volts = ((data/255)*10)/2;
                        temp = (volts*100)-273.15;
                    catch 
                        temp = app.temparray(end);
                        updateplots(app.DialogeApp,0,0,1,0, app.temponoff,0);
    %                                 if strcmp(app.ErrorLogTextArea.Value,'')
    %                                     t = datetime('now');
    %                                     message_disp = append(datestr(t),' Failed to receive analog response from relay board.');
    %                                 else
    %                                     t = datetime('now');
    %                                     message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to receive analog response from relay board.')}];
    %                                 end
    %                                 app.ErrorLogTextArea.Value = message_disp;
                    end
                    if temp < -105
                        temp = app.temparray(end);
                    end
                    if temp < -98
                        app.b = 2;
                    end
                    app.temparray(end+1) = temp;
                    app.tempdata(end+1) = temp;
                    % opening turbo solenoid to make sure
                    fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x6C'; '0x01'; '0x18']))
            
                    % plotting the temperature on first figure
                    updateplots(app.DialogeApp, temp, 0,0,0, app.temponoff,0);
                    if app.c == 1
                        updatingfcn(app);
                        app.c=1;
                    end                            
                end
    
                % plotting the rate on second figure
                rate = (app.temparray(end)-app.temparray(1))/(minutes(datetime("now") - app.starttime));
                app.ratedata(end+1) = rate;
                updateplots(app.DialogeApp,0,app.ratedata,0,0, app.temponoff,0);
                if app.c == 1
                    updatingfcn(app);
                    app.c=1;
                end                        
            end
    timer2Fcn(app);
        end 

        function timer2Fcn(app, ~, ~)
            if app.temponoff == 1
                return
            end 
            while app.b < 3
                % opening turbo solenoid to make sure
                fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x6C'; '0x01'; '0x18']))
            
                % opening or closing ln2 depending on temp found
                fwrite(app.relay, app.tempread)
                try
                    response = fread(app.relay);
                    data = response(end-1);
                    volts = ((data/255)*10)/2;
                    temp = (volts*100)-273.15;
                catch 
                    temp = app.temparray(end);
                    updateplots(app.DialogeApp,0,0,1,0, app.temponoff,0);
%                     if strcmp(app.ErrorLogTextArea.Value,'')
%                         t = datetime('now');
%                         message_disp = append(datestr(t),' Failed to receive analog response from relay board.');
%                     else
%                         t = datetime('now');
%                         message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to receive analog response from relay board.')}];
%                     end
%                     app.ErrorLogTextArea.Value = message_disp;
                end
                if temp < -105
                    temp = app.temparray(end);
                end
            
                if temp < -97 
                    fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x69'; '0x01'; '0x15']))
                end
                if temp > -97
                    fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x71'; '0x01'; '0x1D']))
                end
                app.tempdata(end+1) = temp;

                % setting status ln2 lamp
                fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x79'; '0x01'; '0x25']))
                response = fread(app.relay);
                try
                    updateplots(app.DialogeApp,0,0,0,0,2, app.temponoff,response);
%                     response = fread(app.relay);
%                     if response(end-1)==0
%                         app.LN2SolenoidLamp.Enable = 'off';
%                     else
%                         app.LN2SolenoidLamp.Enable = 'on';
%                     end
                catch 
                    updateplots(app.DialogeApp,0,0,1,0, app.temponoff,0);                    
%                     if strcmp(app.ErrorLogTextArea.Value,'')
%                         t = datetime('now');
%                         message_disp = append(datestr(t),' Failed to receive analog response from relay board.');
%                     else
%                         t = datetime('now');
%                         message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to receive analog response from relay board.')}];
%                     end
%                     app.ErrorLogTextArea.Value = message_disp;
                end
            
                % plotting data
                i = 1;
                while i < 6
                    i = i + 1;
                    % reading relay and finding temperature and cooling rate
                    fwrite(app.relay, app.tempread)
                    try
                        response = fread(app.relay);
                        data = response(end-1);
                        volts = ((data/255)*10)/2;
                        temp = (volts*100)-273.15;
                    catch 
                        updateplots(app.DialogeApp,0,0,1,0, app.temponoff,0);                       
                        temp = app.temparray(end);
%                         if strcmp(app.ErrorLogTextArea.Value,'')
%                             t = datetime('now');
%                             message_disp = append(datestr(t),' Failed to receive analog response from relay board.');
%                         else
%                             t = datetime('now');
%                             message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to receive analog response from relay board.')}];
%                         end
%                         app.ErrorLogTextArea.Value = message_disp;
                    end
                    if temp < -150
                        temp = app.temparray(end);
                    end
                    app.tempdata(end+1) = temp;
                    app.temparray(end+1) = temp;
                    secondlast = app.temparray(end-1);
                    last = app.temparray(end);
                    rate = (last - secondlast)*60;
                    app.ratedata(end+1) = rate;
            
                    % opening turbo solenoid to make sure
                    fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x6C'; '0x01'; '0x18']))
            
                    % plotting the temperature on first figure
                    updateplots(app.DialogeApp, temp, 0,0,0, app.temponoff,0);
                    if app.c == 1
                        updatingfcn(app);
                        app.c=1;
                    end                 
                end

                % plotting the rate on second figure
                tnow= datetime('now');
                rate = (app.temparray(end)-app.temparray(1))/(minutes(tnow - app.starttime));
                app.ratedata(end+1) = rate;
                updateplots(app.DialogeApp,0,app.ratedata,0,0, app.temponoff,0);
                if app.c == 1
                    updatingfcn(app);
                    app.c=1;
                end                
            end
            fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x69'; '0x01'; '0x15']))
        end
    end        
    
    methods (Access = public)
        function magnetsOn(app)
            on = hex2dec(['0xAA';'0x03';'0xFE';'0x6D';'0x01';'0x19']);
            fwrite(app.relay, on);
        end
    end 
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % turning off indicators
            app.UIFigure.WindowState = 'normal';
            app.forelinepress = [];
            app.turbopress = [];
            app.chamberpress = [];
            app.roughpress = [];
            app.gatepress = [];
            app.roughvolt = [];
            app.gn2pressure = [];
            app.Label.BackgroundColor = [1.00,0.00,0.00];
            app.Label_2.BackgroundColor = [1.00,0.00,0.00];
            app.Label_3.BackgroundColor = [1.00,0.00,0.00];
            app.Label_4.BackgroundColor = [1.00,0.00,0.00];
            app.DataUpdatingLoopSwitchLabel.BackgroundColor = [0,1,0];
            app.ChamberPressureStatusLamp.Color = [1.00,0.00,0.00];
            app.PayloadRelayStateLamp.Color = [1,0,0];
            app.GateValveOpenChannelLamp.Color=[1,0,0];
            app.GateValveCloseChannelLamp.Color=[1,0,0];
            app.DataUpdatingLoopSwitch.Value = 'On';
            
            % Clearing Relay to make new one
            echotcpip('off');
            instrreset;

            % creating relay and parameters
            echotcpip('on', 2101);
            app.relay = tcpip('192.168.0.101', 2101);
            app.relay.InputBufferSize = 1000; 
            app.readdata = hex2dec(['0xAA'; '0x02'; '0xFE'; '0xA6'; '0x50']);
            app.allread = hex2dec(['0xAA'; '0x03'; '0xFE'; '0x7C'; '0x01'; '0x28']);
            app.relay.Timeout = 1;
            fopen(app.relay);

            % Obtaining Voltage Measurements
            app.roughvolt = hex2dec([]);
            
%             % Getting Turbo Information
%             % creating visa connection to turbo pump
%             app.turbo = visa('agilent', 'ASRL4::INSTR');
%             app.turbo.InputBufferSize=5100;
%             fopen(app.turbo);
%             app.turbo.Timeout = 0.1;
%             
%             % getting the power from the turbo
%             decdata = hex2dec(['02'; '80'; '32'; '30'; '32'; '30'; '30'; '03'; '42'; '33']);
%             fwrite(app.turbo, decdata, 'uint8')
%             data = fread(app.turbo);
%             power = dec2hex(data);
%             app.Label15.Text = string(power(12,2));
%             
%             % getting the status of turbo pump
%             decdata = hex2dec(['02'; '80'; '32'; '30'; '35'; '30'; '37'; '03'; '42'; '33']);
%             fwrite(app.turbo, decdata, 'uint8')
%             data = fread(app.turbo);
%             pump_state = dec2hex(data);
%             % setting status on GUI
%             if pump_state(12,2)=='0'
%                 app.Label13.Text = 'Stop';
%                 app.TurboSwitch.Value = 'Off';
%                 app.TurboSwitchLabel.BackgroundColor = [1,0,0];                 
%             elseif pump_state(12,2)=='1'
%                 app.Label13.Text = 'Waiting intlk';
%             elseif pump_state(12,2)=='2'
%                 app.Label13.Text = 'Starting';
%                 app.TurboSwitch.Value = 'On';
%                 app.TurboSwitchLabel.BackgroundColor = [0,1,0];                  
%             elseif pump_state(12,2)=='3'
%                 app.Label13.Text = 'Auto-tuning';
%             elseif pump_state(12,2)=='4'
%                 app.Label13.Text = 'Braking';
%             elseif pump_state(12,2)=='5'
%                 app.Label13.Text = 'Normal';
%                 app.TurboSwitch.Value = 'On';
%                 app.TurboSwitchLabel.BackgroundColor = [0,1,0];               
%             else
%                 app.Label13.Text = 'Fail';
%             end
%             % Setting Temp on GUI
%             decdata = hex2dec(['02'; '80'; '32'; '30'; '34'; '30'; '36'; '03'; '42'; '33']);
%             fwrite(app.turbo, decdata, 'uint8')
%             data = fread(app.turbo);
%             pump_temp = dec2hex(data);
%             if pump_temp(10,2)==0
%                 app.Label14.Text = string(append(pump_temp(10,2),pump_temp(11,2),pump_temp(12,2)));
%             else 
%                 app.Label14.Text = string(append(pump_temp(11,2),pump_temp(12,2)));
%             end

           % Get the State of All Relays and Analogs

            fwrite(app.relay, app.allread);
            app.allstatus = flipud(fread(app.relay));
            status = dec2bin(app.allstatus(2));
            statusdata = reverse(status);
            if app.allstatus(2) ~= 0
                if length(statusdata) >= 1
                    if str2double(statusdata(1)) == 0
                        app.TurboSolenoidSwitch.Value = 'Closed';
                        app.TurboSolenoidSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.TurboSolenoidSwitch.Value = 'Open';
                        app.TurboSolenoidSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 2 
                    if str2double(statusdata(2)) == 0
                        app.MagnetsSwitch.Value = 'Off';
                        app.MagnetsSwitchLabel.BackgroundColor = [1,0,0];
                    else
                        app.MagnetsSwitch.Value = 'On';
                        app.MagnetsSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 3
                    if str2double(statusdata(3)) == 0
                        app.GateValveSwitch.Value = 'Closed'; 
                        app.GateValveSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.GateValveSwitch.Value = 'Open';
                        app.GateValveSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 4
                    if str2double(statusdata(4)) == 0
                        app.GN2SolenoidSwitch.Value = 'Closed';
                        app.GN2SolenoidSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.GN2SolenoidSwitch.Value = 'Open';
                        app.GN2SolenoidSwitchLabel.BackgroundColor = [0,1,0];
                    end  
                end 
                if length(statusdata) >= 5 
                    if str2double(statusdata(5)) == 0
                        app.ForelineSolenoidSwitch.Value = 'Closed';
                        app.ForelineSolenoidSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.ForelineSolenoidSwitch.Value = 'Open';
                        app.ForelineSolenoidSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 7 
                    if str2double(statusdata(7)) == 0
                        app.RoughingPumpSwitch.Value = 'Off';
                        app.RoughingPumpSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.RoughingPumpSwitch.Value = 'On';
                        app.RoughingPumpSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) == 8
                    if str2double(statusdata(8)) == 0
                        app.PayloadRelayStateLamp.Color = [1,0,0];
                    else 
                        app.PayloadRelayStateLamp.Color = [0,1,0];
                    end
                end
            end 
           % Initialize Temperature Readout Stuff
            % creating initial measurement number
            app.measurement = 0;

            % initializing temp array for rate calculation & reading relay and finding temperature and cooling rate
            app.tempread = hex2dec(['0xAA'; '0x02'; '0xFE'; '0x96'; '0x40']);
            try
                response = fread(app.relay);
                %if response(end-1) == *add desired temp range*:
                data = response(end-1);
                volts = ((data/255)*10)/2;
                temp = (volts*100)-273.15;
                app.temparray = [temp temp];
            catch
                app.temparray = [20 20];
            end

            % intializing array to store rate data
            app.ratedata = [0 0];
        
           % starting loop for updating gauges and other info
            app.b=0;
            app.temponoff = 0;          
            app.c = 0;
            updatingfcn(app);
        end

        % Button pushed function: ClicktoOpenCoolingInterfaceButton
        function ClicktoOpenCoolingInterfaceButtonPushed(app, event)
            % calls dialog box w inpit values.
            app.DialogeApp = coolingTest_popup(app,app.relay);
        end

        % Callback function
        function CheckTurboButtonPushed(app, event)
%             % getting the power from the turbo
%             decdata = hex2dec(['02'; '80'; '32'; '30'; '32'; '30'; '30'; '03'; '42'; '33']);
%             fwrite(app.turbo, decdata, 'uint8')
%             data = fread(app.turbo);
%             power = dec2hex(data);
%             app.Label6.Text = string(power(12,2));
%             
%             % getting the status of turbo pump
%             decdata = hex2dec(['02'; '80'; '32'; '30'; '35'; '30'; '37'; '03'; '42'; '33']);
%             fwrite(app.turbo, decdata, 'uint8')
%             data = fread(app.turbo);
%             pump_state = dec2hex(data);
%             % setting status on GUI
%             if pump_state(12,2)=='0'
%                 app.Label5.Text = 'Stop';
%                 app.TurboSwitch.Value = 'Off';
%                 app.TurboSwitchLabel.BackgroundColor = [1,0,0];
%             elseif pump_state(12,2)=='1'
%                 app.Label5.Text = 'Waiting intlk';             
%             elseif pump_state(12,2)=='2'
%                 app.Label5.Text = 'Starting';
%                 app.TurboSwitch.Value = 'On';
%                 app.TurboSwitchLabel.BackgroundColor = [0,1,0];              
%             elseif pump_state(12,2)=='3'
%                 app.Label5.Text = 'Auto-tuning';
%             elseif pump_state(12,2)=='4'
%                 app.Label5.Text = 'Braking';
%             elseif pump_state(12,2)=='5'
%                 app.Label5.Text = 'Normal';
%                 app.TurboSwitch.Value = 'On';
%                 app.TurboSwitchLabel.BackgroundColor = [0,1,0];                  
%             else
%                 app.Label5.Text = 'Fail';
%             end
%             % getting temperature from turbo pump
%             decdata = hex2dec(['02'; '80'; '32'; '30'; '34'; '30'; '36'; '03'; '42'; '33']);
%             fwrite(app.turbo, decdata, 'uint8')
%             data = fread(app.turbo);
%             pump_temp = dec2hex(data); 
%             if pump_state(10,2)==0
%                 app.Label7.Text = string(append(pump_temp(10,2),pump_temp(11,2),pump_temp(12,2)));
%             else 
%                 app.Label7.Text = string(append(pump_temp(11,2),pump_temp(12,2)));
%             end
        end

        % Value changed function: RoughingPumpSwitch
        function RoughingPumpSwitchValueChanged(app, event)
            value = app.RoughingPumpSwitch.Value;
            if strcmp(value, 'On')
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x72'; '0x01'; '0x1E'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            else
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x6A'; '0x01'; '0x16'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            end

            % Changing Switch Color
            if strcmp(value,'On')
                app.RoughingPumpSwitchLabel.BackgroundColor = [0.00,1.00,0.00];
            else
                app.RoughingPumpSwitchLabel.BackgroundColor = [1.00,0.00,0.00];
            end
        end

        % Value changed function: ForelineSolenoidSwitch
        function ForelineSolenoidSwitchValueChanged(app, event)
            value = app.ForelineSolenoidSwitch.Value;
            if strcmp(value, 'Open')
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x70'; '0x01'; '0x1C'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            else
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x68'; '0x01'; '0x14'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            end

            if strcmp(value, 'Open')
                app.ForelineSolenoidSwitchLabel.BackgroundColor=[0.00,1.00,0.00];
            else
                app.ForelineSolenoidSwitchLabel.BackgroundColor=[1.00,0.00,0.00];
            end
        end

        % Value changed function: TurboSolenoidSwitch
        function TurboSolenoidSwitchValueChanged(app, event)
            value = app.TurboSolenoidSwitch.Value;
            if strcmp(value, 'Open')
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x6C'; '0x01'; '0x18'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            else
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x64'; '0x01'; '0x10'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            end

            if strcmp(value,'Open')
                app.TurboSolenoidSwitchLabel.BackgroundColor = [0.00,1.00,0.00];
            else
                app.TurboSolenoidSwitchLabel.BackgroundColor = [1.00,0.00,0.00];
            end
        end

        % Value changed function: GateValveSwitch
        function GateValveSwitchValueChanged(app, event)
            value = app.GateValveSwitch.Value;
            if strcmp(value, 'Open')
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x6E'; '0x01'; '0x1A'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            else
                % writing to relay 3
                hex = ['0xAA'; '0x03'; '0xFE'; '0x66'; '0x01'; '0x12'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            end
            if strcmp(value,'Open')
                app.GateValveSwitchLabel.BackgroundColor = [0.00,1.00,0.00];
            else
                app.GateValveSwitchLabel.BackgroundColor = [1.00,0.00,0.00];
            end
        end

        % Value changed function: GN2SolenoidSwitch
        function GN2SolenoidSwitchValueChanged(app, event)
            value = app.GN2SolenoidSwitch.Value;
            if strcmp(value, 'Open')
                % writing to relay 1
                hex = ['0xAA'; '0x03'; '0xFE'; '0x6F'; '0x01'; '0x1B'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            else
                % writing to relay 1
                hex = ['0xAA'; '0x03'; '0xFE'; '0x67'; '0x01'; '0x13'];
                data = hex2dec(hex);
                fwrite(app.relay, data);
            end
            if strcmp(value, 'Open')
                app.GN2SolenoidSwitchLabel.BackgroundColor = [0.00,1.00,0.00];
            else
                app.GN2SolenoidSwitchLabel.BackgroundColor = [1.00,0.00,0.00];
            end
        end

        % Value changed function: MagnetsSwitch
        function MagnetsSwitchValueChanged(app, event)
            value = app.MagnetsSwitch.Value;
            if strcmp(value, 'On')
                on = hex2dec(['0xAA';'0x03';'0xFE';'0x6D';'0x01';'0x19']);
                fwrite(app.relay,on);
            else
                off = hex2dec(['0xAA';'0x03';'0xFE';'0x65';'0x01';'0x11']);
                fwrite(app.relay, off);
            end
            if strcmp(value,'On')
                app.MagnetsSwitchLabel.BackgroundColor=[0.00,1.00,0.00];
            else
                app.MagnetsSwitchLabel.BackgroundColor=[1.00,0.00,0.00];
            end
        end

        % Callback function
        function TurboSwitchValueChanged(app, event)
%             value = app.TurboSwitch.Value;
%             if strcmp(value, 'On')
%                 decdata = hex2dec(['02'; '80'; '30'; '30'; '30'; '31'; '31'; '03'; '42'; '33']);
%                 fwrite(app.turbo, decdata, 'uint8');
%                 app.TurboSwitchLabel.BackgroundColor = [0.00,1.00,0.00];
%             end
%             if strcmp(value, 'Off')
%                 decdata = hex2dec(['02'; '80'; '30'; '30'; '30'; '31'; '30'; '03'; '42'; '32']);
%                 fwrite(app.turbo, decdata, 'uint8');
%                 app.TurboSwitchLabel.BackgroundColor = [1.00,0.00,0.00];                
%             end          
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            time =datestr(now, 'yyyymmmdd_HHMMSS');
            
            filename = sprintf('chamberpressure_%s.txt',time);
            writematrix(app.chamberpress, filename);
            
            filename = sprintf('forelinepressure_%s.txt',time);
            writematrix(app.forelinepress, filename);
            
            filename = sprintf('turbosolenoid_%s.txt',time);
            writematrix(app.turbopress, filename);
            
            filename = sprintf('gn2pressure_%s.txt',time);
            writematrix(app.gn2pressure, filename);
            
            fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x69'; '0x01'; '0x15']));
            fclose(app.relay);
            echotcpip('Off');
            instrreset;
            delete(app.relay);
            delete(app)
        end

        % Value changed function: DataUpdatingLoopSwitch
        function DataUpdatingLoopSwitchValueChanged(app, event)
            value = app.DataUpdatingLoopSwitch.Value;
            if app.c == 0 | app.c == 1
                if strcmp(value,'On') 
                    app.c = 0;
                    app.DataUpdatingLoopSwitchLabel.BackgroundColor = [0,1,0];
                    updatingfcn(app);                
                end
                if strcmp(value,'Off')
                    app.c = 2;
                    app.DataUpdatingLoopSwitchLabel.BackgroundColor = [1,0,0]; 
                end 
            end 
            if app.c == 2
                if strcmp(value,'On') 
                    app.c = 0;
                    app.DataUpdatingLoopSwitchLabel.BackgroundColor = [0,1,0];
                    updatingfcn(app);
                end
                if strcmp(value,'Off')
                    app.c = 2;
                    app.DataUpdatingLoopSwitchLabel.BackgroundColor = [1,0,0]; 
                end 
            end 
        end

        % Button pushed function: StartCoolingPushOpenCoolingFirstButton
        function StartCoolingPushOpenCoolingFirstButtonPushed(app, event)
            % Restart Loop with Cooling Function
            app.starttime = datetime('now');
            if app.c == 0 | app.c == 1
                app.c = 2;
                app.DataUpdatingLoopSwitch.Value = 'On';
                app.DataUpdatingLoopSwitchLabel.BackgroundColor = [0,1,0];
                app.c = 1;
                timer1fcn(app);
            else
                app.c = 2;
                app.temponoff = 0;
                app.DataUpdatingLoopSwitch.Value = 'Off';
                app.DataUpdatingLoopSwitchLabel.BackgroundColor = [1,0,0];
                timer1fcn(app);
            end 
            
        end

        % Button pushed function: 
        % StopCoolingClickBeforeClosingCoolingWindowButton
        function StopCoolingClickBeforeClosingCoolingWindowButtonPushed(app, event)
            app.b = 5;
            app.temponoff = 1; 
            if app.c == 0 | app.c == 1
                app.c=2;
                app.DataUpdatingLoopSwitch.Value = 'On';
                app.DataUpdatingLoopSwitchLabel.BackgroundColor = [0,1,0];
                app.c=0;
            else 
                app.c = 2;
                app.DataUpdatingLoopSwitch.Value = 'Off';
                app.DataUpdatingLoopSwitchLabel.BackgroundColor = [1,0,0];   
            end 
            timer1fcn(app);
            updatingfcn(app);
            
            fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x69'; '0x01'; '0x15']))
            
            % Saving the temp and rate data
            time = datestr(now, 'yyyymmmdd_HHMMSS');
            
            filename = sprintf('coolingData_%s.txt',time);
            writematrix(app.tempdata, filename);
            
            filename = sprintf('ratedata_%s.txt',time);
            writematrix(app.ratedata, filename);             
        end

        % Button pushed function: CheckRelayStatusButton
        function CheckRelayStatusButtonPushed(app, event)
            fwrite(app.relay, app.allread);
            app.allstatus = flipud(fread(app.relay));
            status = dec2bin(app.allstatus(2));
            statusdata = reverse(status);
            if app.allstatus(2) ~= 0
                if length(statusdata) >= 1
                    if str2double(statusdata(1)) == 0
                        app.TurboSolenoidSwitch.Value = 'Closed';
                        app.TurboSolenoidSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.TurboSolenoidSwitch.Value = 'Open';
                        app.TurboSolenoidSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 2 
                    if str2double(statusdata(2)) == 0
                        app.MagnetsSwitch.Value = 'Off';
                        app.MagnetsSwitchLabel.BackgroundColor = [1,0,0];
                    else
                        app.MagnetsSwitch.Value = 'On';
                        app.MagnetsSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 3
                    if str2double(statusdata(3)) == 0
                        app.GateValveSwitch.Value = 'Closed'; 
                        app.GateValveSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.GateValveSwitch.Value = 'Open';
                        app.GateValveSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 4
                    if str2double(statusdata(4)) == 0
                        app.GN2SolenoidSwitch.Value = 'Closed';
                        app.GN2SolenoidSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.GN2SolenoidSwitch.Value = 'Open';
                        app.GN2SolenoidSwitchLabel.BackgroundColor = [0,1,0];
                    end  
                end 
                if length(statusdata) >= 5 
                    if str2double(statusdata(5)) == 0
                        app.ForelineSolenoidSwitch.Value = 'Closed';
                        app.ForelineSolenoidSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.ForelineSolenoidSwitch.Value = 'Open';
                        app.ForelineSolenoidSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) >= 7 
                    if str2double(statusdata(7)) == 0
                        app.RoughingPumpSwitch.Value = 'Off';
                        app.RoughingPumpSwitchLabel.BackgroundColor = [1,0,0];
                    else 
                        app.RoughingPumpSwitch.Value = 'On';
                        app.RoughingPumpSwitchLabel.BackgroundColor = [0,1,0];
                    end
                end 
                if length(statusdata) == 8
                    if str2double(statusdata(8)) == 0
                        app.PayloadRelayStateLamp.Color = [1,0,0];
                    else 
                        app.PayloadRelayStateLamp.Color = [0,1,0];
                    end
                end
            end 
        end

        % Value changed function: FireSubpayloadButton
        function FireSubpayloadButtonValueChanged(app, event)
            value = app.FireSubpayloadButton.Value;
            if value == 1           
                fwrite(app.relay,hex2dec(['0xAA'; '0x03'; '0xFE'; '0x73'; '0x01'; '0x1F']));
                app.PayloadRelayStateLamp.Color = [0,1,0];
            else
                fwrite(app.relay,hex2dec(['0xAA'; '0x03'; '0xFE'; '0x6B'; '0x01'; '0x17']));
                app.PayloadRelayStateLamp.Color = [1,0,0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GN2SystemPanel = uipanel(app.UIFigure);
            app.GN2SystemPanel.Title = 'GN2 System';
            app.GN2LinePressurepsiGauge = uigauge(app.GN2SystemPanel, 'circular');
            app.GN2LinePressurepsiGauge.MajorTicks = [0 25 50 65 75 100];
            app.GN2SolenoidSwitchLabel = uilabel(app.GN2SystemPanel);
            app.GN2SolenoidSwitchLabel.Text = 'GN2 Solenoid Switch';
            app.GN2SolenoidSwitch = uiswitch(app.GN2SystemPanel, 'slider');
            app.GN2SolenoidSwitch.Items = {'Closed', 'Open'};
            app.GN2SolenoidSwitch.ValueChangedFcn = createCallbackFcn(app, @GN2SolenoidSwitchValueChanged, true);
            app.GN2SolenoidSwitch.Value = 'Closed';
            app.Label12 = uilabel(app.GN2SystemPanel);
            app.Label12.Text = 'Label12';
            app.GN2LinePressurePsiLabel = uilabel(app.GN2SystemPanel);
            app.GN2LinePressurePsiLabel.Text = 'GN2 Line Pressure (Psi):';
            app.RailPumpingSystemPanel = uipanel(app.UIFigure);
            app.RailPumpingSystemPanel.Title = 'Rail Pumping System';
            app.Image = uiimage(app.RailPumpingSystemPanel);
            app.Image.ImageSource = 'roughing pump inmage.png';
            app.Image3 = uiimage(app.RailPumpingSystemPanel);
            app.Image3.ImageSource = 'solenoid image.png';
            app.TurboSolenoidSwitchLabel = uilabel(app.RailPumpingSystemPanel);
            app.TurboSolenoidSwitchLabel.Text = 'Turbo Solenoid';
            app.TurboSolenoidSwitch = uiswitch(app.RailPumpingSystemPanel, 'slider');
            app.TurboSolenoidSwitch.Items = {'Closed', 'Open'};
            app.TurboSolenoidSwitch.ValueChangedFcn = createCallbackFcn(app, @TurboSolenoidSwitchValueChanged, true);
            app.TurboSolenoidSwitch.Value = 'Closed';
            app.Image5 = uiimage(app.RailPumpingSystemPanel);
            app.Image5.ImageSource = 'solenoid image.png';
            app.ForelineSolenoidSwitchLabel = uilabel(app.RailPumpingSystemPanel);
            app.ForelineSolenoidSwitchLabel.Text = 'Foreline Solenoid';
            app.ForelineSolenoidSwitch = uiswitch(app.RailPumpingSystemPanel, 'slider');
            app.ForelineSolenoidSwitch.Items = {'Closed', 'Open'};
            app.ForelineSolenoidSwitch.ValueChangedFcn = createCallbackFcn(app, @ForelineSolenoidSwitchValueChanged, true);
            app.ForelineSolenoidSwitch.Value = 'Closed';
            app.RoughingPumpSwitchLabel = uilabel(app.RailPumpingSystemPanel);
            app.RoughingPumpSwitchLabel.Text = 'Roughing Pump';
            app.RoughingPumpSwitch = uiswitch(app.RailPumpingSystemPanel, 'slider');
            app.RoughingPumpSwitch.ValueChangedFcn = createCallbackFcn(app, @RoughingPumpSwitchValueChanged, true);
            app.Label = uilabel(app.RailPumpingSystemPanel);
            app.Label.Text = ' ';
            app.Label_2 = uilabel(app.RailPumpingSystemPanel);
            app.Label_2.Text = ' ';
            app.Image6 = uiimage(app.RailPumpingSystemPanel);
            app.Image6.ImageSource = 'solenoid image.png';
            app.Label_4 = uilabel(app.RailPumpingSystemPanel);
            app.Label_4.Text = ' ';
            app.GateValveSwitchLabel = uilabel(app.RailPumpingSystemPanel);
            app.GateValveSwitchLabel.Text = 'Gate Valve Switch';
            app.GateValveSwitch = uiswitch(app.RailPumpingSystemPanel, 'slider');
            app.GateValveSwitch.Items = {'Closed', 'Open'};
            app.GateValveSwitch.ValueChangedFcn = createCallbackFcn(app, @GateValveSwitchValueChanged, true);
            app.GateValveSwitch.Value = 'Closed';
            app.GateValveOpenChannelLabel = uilabel(app.RailPumpingSystemPanel);
            app.GateValveOpenChannelLabel.Text = 'Gate Valve Open Channel ';
            app.GateValveOpenChannelLamp = uilamp(app.RailPumpingSystemPanel);
            app.GateValveCloseChannelLampLabel = uilabel(app.RailPumpingSystemPanel);
            app.GateValveCloseChannelLampLabel.Text = 'Gate Valve Close Channel';
            app.GateValveCloseChannelLamp = uilamp(app.RailPumpingSystemPanel);
            app.Label8 = uilabel(app.RailPumpingSystemPanel);
            app.Label8.Text = 'Label8';
            app.Label9 = uilabel(app.RailPumpingSystemPanel);
            app.Label9.Text = 'Label9';
            app.Label10 = uilabel(app.RailPumpingSystemPanel);
            app.Label10.Text = 'Label10';
            app.RoughingPumpCurrentDrawVLabel = uilabel(app.RailPumpingSystemPanel);
            app.RoughingPumpCurrentDrawVLabel.Text = 'Roughing Pump Current Draw (V):';
            app.ForelinePressureTorrLabel = uilabel(app.RailPumpingSystemPanel);
            app.ForelinePressureTorrLabel.Text = 'Foreline Pressure: (Torr)';
            app.TurboPressureTorrLabel = uilabel(app.RailPumpingSystemPanel);
            app.TurboPressureTorrLabel.Text = 'Turbo Pressure (Torr):';
            app.RoughingPumpGauge = uigauge(app.RailPumpingSystemPanel, 'linear');
            app.RoughingPumpGauge.Limits = [0 5];
            app.Label_3 = uilabel(app.RailPumpingSystemPanel);
            app.Label_3.Text = ' ';
            app.Label11 = uilabel(app.RailPumpingSystemPanel);
            app.Label11.Text = 'Label11';
            app.ChamberPressureTorrLabel = uilabel(app.RailPumpingSystemPanel);
            app.ChamberPressureTorrLabel.Text = 'Chamber Pressure (Torr):';
            app.ChamberPressureStatusLampLabel = uilabel(app.RailPumpingSystemPanel);
            app.ChamberPressureStatusLampLabel.Text = 'Chamber Pressure Status';
            app.ChamberPressureStatusLamp = uilamp(app.RailPumpingSystemPanel);
            app.Image7 = uiimage(app.RailPumpingSystemPanel);
            app.Image7.ImageSource = 'Pressurized_vessel_vertical.svg.png';
            app.CoolingSystemPanel = uipanel(app.UIFigure);
            app.CoolingSystemPanel.Title = 'Cooling System';
            app.ClicktoOpenCoolingInterfaceButton = uibutton(app.CoolingSystemPanel, 'push');
            app.ClicktoOpenCoolingInterfaceButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoOpenCoolingInterfaceButtonPushed, true);
            app.ClicktoOpenCoolingInterfaceButton.Text = 'Click to Open Cooling Interface';
            app.StartCoolingPushOpenCoolingFirstButton = uibutton(app.CoolingSystemPanel, 'push');
            app.StartCoolingPushOpenCoolingFirstButton.ButtonPushedFcn = createCallbackFcn(app, @StartCoolingPushOpenCoolingFirstButtonPushed, true);
            app.StartCoolingPushOpenCoolingFirstButton.Text = {'Start Cooling '; '(Push Open Cooling First)'};
            app.StopCoolingClickBeforeClosingCoolingWindowButton = uibutton(app.CoolingSystemPanel, 'push');
            app.StopCoolingClickBeforeClosingCoolingWindowButton.ButtonPushedFcn = createCallbackFcn(app, @StopCoolingClickBeforeClosingCoolingWindowButtonPushed, true);
            app.StopCoolingClickBeforeClosingCoolingWindowButton.Text = {'Stop Cooling'; '(Click Before Closing Cooling Window) '};
            app.LoopControlsPanel = uipanel(app.UIFigure);
            app.LoopControlsPanel.Title = 'Loop Controls';
            app.DataUpdatingLoopSwitchLabel = uilabel(app.LoopControlsPanel);
            app.DataUpdatingLoopSwitchLabel.Text = 'Data Updating Loop';
            app.DataUpdatingLoopSwitch = uiswitch(app.LoopControlsPanel, 'slider');
            app.DataUpdatingLoopSwitch.ValueChangedFcn = createCallbackFcn(app, @DataUpdatingLoopSwitchValueChanged, true);
            app.CheckRelayStatusButton = uibutton(app.LoopControlsPanel, 'push');
            app.CheckRelayStatusButton.ButtonPushedFcn = createCallbackFcn(app, @CheckRelayStatusButtonPushed, true);
            app.CheckRelayStatusButton.Text = {'Check Relay '; 'Status '};
            app.MagnetsPanel = uipanel(app.UIFigure);
            app.MagnetsPanel.Title = 'Magnets';
            app.MagnetsSwitchLabel = uilabel(app.MagnetsPanel);
            app.MagnetsSwitchLabel.Text = 'Magnets';
            app.MagnetsSwitch = uiswitch(app.MagnetsPanel, 'slider');
            app.MagnetsSwitch.ValueChangedFcn = createCallbackFcn(app, @MagnetsSwitchValueChanged, true);
            app.FireSubpaylodPanel = uipanel(app.UIFigure);
            app.FireSubpaylodPanel.Title = 'Fire Subpaylod';
            app.PayloadRelayStateLampLabel = uilabel(app.FireSubpaylodPanel);
            app.PayloadRelayStateLampLabel.Text = 'Payload Relay State';
            app.PayloadRelayStateLamp = uilamp(app.FireSubpaylodPanel);
            app.FireSubpayloadButton = uibutton(app.FireSubpaylodPanel, 'state');
            app.FireSubpayloadButton.ValueChangedFcn = createCallbackFcn(app, @FireSubpayloadButtonValueChanged, true);
            app.FireSubpayloadButton.Text = 'Fire Subpayload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end