classdef coolingTest_popup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TemperaturecLabel      matlab.ui.control.Label
        Label3                 matlab.ui.control.Label
        Label2                 matlab.ui.control.Label
        CoolingRateLabel       matlab.ui.control.Label
        ErrorLogTextArea       matlab.ui.control.TextArea
        ErrorLogTextAreaLabel  matlab.ui.control.Label
        LN2SolenoidLamp        matlab.ui.control.Lamp
        LN2SolenoidLampLabel   matlab.ui.control.Label
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        readdata % variable to read analog from relay
        readstatus % variable to read status of relays
        measurement % number to keep track number of measurements
        temparray % creating array to store temps for rate
        tempdata % creating array to save temp data
        ratedata % creating array to save rate data
        ratetime % variable to keep track of time for rates
        xll1 % variable to define lower limit of x-axis of plot1
        xul1 % variable to define upper limit of x-axis of plot1
        xll2 % variable to define lower limit of x-axis of plot2
        xul2 % variable to define upper limit of x-axis of plot2
        b % variable to control while loops
        currentTemp % variable to store current temperature data
        starttime % variable to calculate cooling rate
        chamber % variable to store chamber pressure values
        relay  % variable for relay connection
        mainapp % main 
    end
    
    methods (Access = public)
        function updateplots(app, y1, y2, errorlog, solenoidstate, onoff, ln2response)
            if onoff == 1
                return 
            end 
            if y1 ~= 0
               t = datetime('now');
               if(t >= app.starttime + minutes(5))
                   cla(app.UIAxes);
                   xtra = minutes(5);
                   app.UIAxes.XLim = [app.xll1+xtra t+xtra];
                   app.UIAxes.XTick = linspace(app.xll1+xtra, t+xtra, 5);
                   app.xll1 = app.xll1 + xtra;
                   app.xul1 = t + xtra;
                   app.starttime = datetime('now');
                end
                plot(app.UIAxes, t, y1, 'o', 'Color', [0.3010 0.7450 0.9330]);
                app.Label3.Text = string(y1(end));
                pause(.5)
            end 
            if isempty(y2) == false 
%                t2 = datetime('now');
%                 if(t2 >= app.starttime + minutes(5))
%                    cla(app.UIAxes_2);
%                    xtra = minutes(5);
%                    app.UIAxes_2.XLim = [app.xll2+xtra tnow+xtra];
%                    app.UIAxes_2.XTick = linspace(app.xll2+xtra, t2+xtra, 5);
%                    app.xll2 = app.xll2 + xtra;
%                    app.xul2 = t2 + xtra;
%                    app.starttime = datetime('now');
%                 end
                plot(app.UIAxes_2, y2, 'Color', [0.3010 0.7450 0.9330]);
                app.Label2.Text = string(y2(end));
                pause(.5)
            end 
            if errorlog == 1
                if strcmp(app.ErrorLogTextArea.Value,'')
                    t = datetime('now');
                    message_disp = append(datestr(t),' Failed to receive analog response from relay board.');
                else
                    t = datetime('now');
                    message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to receive analog response from relay board.')}];
                end
                app.ErrorLogTextArea.Value = message_disp;  
            end
            if errorlog == 3
                if strcmp(app.ErrorLogTextArea.Value,'')
                    t = datetime('now');
                    message_disp = append(datestr(t),' Failed to plot rate data.');
                else
                    t = datetime('now');
                    message_disp = [app.ErrorLogTextArea.Value(:)', {append(datestr(t),' Failed to plot rate data.')}];
                end 
                app.ErrorLogTextArea.Value = message_disp;
            end 
            if solenoidstate == 2
                response = ln2response;
                if response(end-1)==0
                    app.LN2SolenoidLamp.Enable = 'Off';
                else
                    app.LN2SolenoidLamp.Enable = 'On';
                end    
            end 
            if solenoidstate == 1
                app.LN2SolenoidLamp.Enable = 'Off';
            end 
            if solenoidstate == 0
                app.LN2SolenoidLamp.Enable = 'On';         
            end 
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, relaydata)
            app.mainapp = mainapp;

            % Initialize Starttime 
            app.starttime = datetime('now');

            % creating initial measurement number
            app.measurement = 0;

            % initializing temp array for rate calculation
            app.temparray = [20 20];

            % intializing array to store temperature and rate data
            app.ratedata = [0 0];
            app.tempdata = [0 0];
            
            % initializing starting time for rate calculations
            %app.starttime = datetime('now');

            % intializing array to store chamber pressure
            app.chamber = [0 0];

%             % creating relay and parameters
%             app.relay = relaydata; 
%             app.readdata = hex2dec(['0xAA'; '0x02'; '0xFE'; '0x96'; '0x40']);
%             app.readstatus = hex2dec(['0xAA'; '0x03'; '0xFE'; '0x7C'; '0x01'; '0x28']);
% 
%             % Plot invisible data point to initialize datetime ruler
            plot(app.UIAxes, datetime('now'), 20, 'MarkerEdgeColor', 'none');
%            plot(app.UIAxes_2, datetime('now'), 20, 'MarkerEdgeColor', 'none');

            % Set x limits and ticks on temp axes
            t = datetime('now') + seconds(1:60);
            app.xll1 = t(1)-seconds(5);
            app.xul1 = t(length(t));
            app.UIAxes.XLim = [app.xll1 app.xul1];
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.XAxis.TickLabelFormat = 'hh:mm:ss';
            grid(app.UIAxes,'on');
            hold(app.UIAxes,'on');
            
            % Set x limits and ticks on rate axis
%             app.xll2 = t(1)-seconds(5);
%             app.xul2 = t(length(t));
%             app.UIAxes_2.XLim = [app.xll2 app.xul2];
%             app.UIAxes_2.XLimMode = 'auto';
%             app.UIAxes_2.XAxis.TickLabelFormat = 'hh:mm:ss';
%             grid(app.UIAxes_2,'on');
%             hold(app.UIAxes_2,'on');

            app.xll2 = 0;
            app.xul2 = 100;
            app.UIAxes_2.XLim = [0 100];
            app.UIAxes_2.XLimMode = 'auto';
            app.UIAxes_2.XTick = linspace(0, 100, 5);
            grid(app.UIAxes_2,'on');
%             hold(app.UIAxes_2,'on');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % closing ln2 solenoid
%             fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x69'; '0x01'; '0x15']));
            
            % saving temp and rate data to txt files            
%             save('ratedata.txt', app.ratedata) ~> old version
%             fileID = fopen('ratedata.txt', 'w')
%             fprintf(fileID, '%d\r\n', app.ratedata);
%             fclose(fileID);
%             
%             fileID = fopen('tempdata.txt', 'w')
%             fprintf(fileID, '%d\r\n', app.tempdata);
%             fclose(fileID);

            % closing, deleting, and clearing relay connection
%            fclose(app.relay);
%            echotcpip('off');
            
            % closing application
            delete(app)
        end

        % Callback function
        function StartCoolingButtonPushed(app, event)
%             execute(app.mainapp, 1, 0);
%             executioncontrol(app,app.a);
        end

        % Callback function
        function StopCoolingButtonPushed(app, event)
%             app.b = 5;
%             execute(app.mainapp, 0, 1);
%             fwrite(app.relay, hex2dec(['0xAA'; '0x03'; '0xFE'; '0x69'; '0x01'; '0x15']))
%             writematrix(app.tempdata, 'coolingData.txt')
%             writematrix(app.chamber, 'chamberPressure.txt')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Current Temperature')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature (C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Current Cooling Rate')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Cooling Rate (C/seconds)')
            zlabel(app.UIAxes_2, 'Z')
            app.LN2SolenoidLampLabel = uilabel(app.UIFigure);
            app.LN2SolenoidLampLabel.Text = 'LN2 Solenoid';
            app.LN2SolenoidLamp = uilamp(app.UIFigure);
            app.ErrorLogTextAreaLabel = uilabel(app.UIFigure);
            app.ErrorLogTextAreaLabel.Text = 'Error Log';
            app.ErrorLogTextArea = uitextarea(app.UIFigure);
            app.CoolingRateLabel = uilabel(app.UIFigure);
            app.CoolingRateLabel.Text = 'Cooling Rate:';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.TemperaturecLabel = uilabel(app.UIFigure);
            app.TemperaturecLabel.Text = 'Temperature (c):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coolingTest_popup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end